classdef SimpleAntenna1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        PlotButton                     matlab.ui.control.Button
        S1SliderLabel                  matlab.ui.control.Label
        S1Slider                       matlab.ui.control.Slider
        S2SliderLabel                  matlab.ui.control.Label
        S2Slider                       matlab.ui.control.Slider
        S4SliderLabel                  matlab.ui.control.Label
        S4Slider                       matlab.ui.control.Slider
        S3SliderLabel                  matlab.ui.control.Label
        S3Slider                       matlab.ui.control.Slider
        S5SliderLabel                  matlab.ui.control.Label
        S5Slider                       matlab.ui.control.Slider
        ResetButton                    matlab.ui.control.Button
        ExcitationstrengthvaluesLabel  matlab.ui.control.Label
        functionButtonGroup            matlab.ui.container.ButtonGroup
        cosxButton                     matlab.ui.control.RadioButton
        sinxButton                     matlab.ui.control.RadioButton
        plotfunctionButton             matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        PanelPolar                     matlab.ui.container.Panel
        ComputationOfAntennaArrayPaternsplotLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        UIPolar % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIPolar = polaraxes(app.PanelPolar);
        end

        % Callback function: PlotButton, S1Slider, S2Slider, 
        % S3Slider, S4Slider, S5Slider
        function PlopButtonPushed(app, event)
            
%Example got from:
%https://www.mathworks.com/matlabcentral/fileexchange/41256-computation-of-antenna-array-paterns-plot
            
% Excitation Strength
s1 = app.S1Slider.Value;
s2 = app.S2Slider.Value;
s3 = app.S3Slider.Value;
s4 = app.S4Slider.Value;
s5 = app.S5Slider.Value;
E5=[s1 s2 s3 s4 s5];


% Theta 
theta=-2*pi:0.001:2*pi;
% Speed of Light
c=3*10^8;
% Frequency 
f=1000*10^6; 
beta=0;
% Lambda value
lambda= c/f;
% Distance 
d=lambda/2;
k= 2*pi/lambda;

Afr5= E5(1)+((E5(2)*cos(k*d*cos(theta))))+((E5(3)*cos(2*k*d*cos(theta))))+((E5(4)*cos(3*k*d*cos(theta))))+((E5(5)*cos(4*k*d*cos(theta))));
Afi5=((E5(2)*sin(k*d*cos(theta))))+((E5(3)*sin(2*k*d*cos(theta))))+((E5(4)*sin(3*k*d*cos(theta))))+((E5(5)*(sin(4*k*d*cos(theta)))));
ff5=abs(complex(Afr5,Afi5));

polarplot(app.UIPolar,theta,ff5);

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
app.S1Slider.Value=1;
app.S2Slider.Value=1;
app.S3Slider.Value=1;
app.S4Slider.Value=1;
app.S5Slider.Value=1;
        end

        % Button pushed function: plotfunctionButton
        function plotfunctionButtonPushed(app, event)
            x = linspace(0,2*pi,1000);
            if(app.cosxButton.Value == true)
                y=cos(x);
            elseif app.sinxButton.Value == true
                y=sin(x);       
            end
            plot(app.UIAxes,x,y);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {722, 722};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {263, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlopButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.S1SliderLabel = uilabel(app.LeftPanel);
            app.S1SliderLabel.Text = 'S1';
            app.S1Slider = uislider(app.LeftPanel);
            app.S1Slider.Limits = [0 10];
            app.S1Slider.ValueChangedFcn = createCallbackFcn(app, @PlopButtonPushed, true);
            app.S1Slider.Value = 1;
            app.S2SliderLabel = uilabel(app.LeftPanel);
            app.S2SliderLabel.Text = 'S2';
            app.S2Slider = uislider(app.LeftPanel);
            app.S2Slider.Limits = [0 10];
            app.S2Slider.ValueChangedFcn = createCallbackFcn(app, @PlopButtonPushed, true);
            app.S2Slider.Value = 1;
            app.S4SliderLabel = uilabel(app.LeftPanel);
            app.S4SliderLabel.Text = 'S4';
            app.S4Slider = uislider(app.LeftPanel);
            app.S4Slider.Limits = [0 10];
            app.S4Slider.ValueChangedFcn = createCallbackFcn(app, @PlopButtonPushed, true);
            app.S4Slider.Value = 1;
            app.S3SliderLabel = uilabel(app.LeftPanel);
            app.S3SliderLabel.Text = 'S3';
            app.S3Slider = uislider(app.LeftPanel);
            app.S3Slider.Limits = [0 10];
            app.S3Slider.ValueChangedFcn = createCallbackFcn(app, @PlopButtonPushed, true);
            app.S3Slider.Value = 1;
            app.S5SliderLabel = uilabel(app.LeftPanel);
            app.S5SliderLabel.Text = 'S5';
            app.S5Slider = uislider(app.LeftPanel);
            app.S5Slider.Limits = [0 10];
            app.S5Slider.ValueChangedFcn = createCallbackFcn(app, @PlopButtonPushed, true);
            app.S5Slider.Value = 1;
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExcitationstrengthvaluesLabel = uilabel(app.LeftPanel);
            app.ExcitationstrengthvaluesLabel.Text = 'Excitation strength values:';
            app.plotfunctionButton = uibutton(app.LeftPanel, 'push');
            app.plotfunctionButton.ButtonPushedFcn = createCallbackFcn(app, @plotfunctionButtonPushed, true);
            app.plotfunctionButton.Text = 'plot function';
            app.functionButtonGroup = uibuttongroup(app.LeftPanel);
            app.functionButtonGroup.Title = 'function';
            app.cosxButton = uiradiobutton(app.functionButtonGroup);
            app.cosxButton.Text = 'cos(x)';
            app.cosxButton.Value = true;
            app.sinxButton = uiradiobutton(app.functionButtonGroup);
            app.sinxButton.Text = 'sin(x)';
            app.RightPanel = uipanel(app.GridLayout);
            app.PanelPolar = uipanel(app.RightPanel);
            app.PanelPolar.Title = 'Panel';
            app.ComputationOfAntennaArrayPaternsplotLabel = uilabel(app.RightPanel);
            app.ComputationOfAntennaArrayPaternsplotLabel.Text = 'Computation Of Antenna Array Paterns plot';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Function')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleAntenna1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end